classdef BLDC < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                       matlab.ui.Figure
        Image                          matlab.ui.control.Image
        Panel                          matlab.ui.container.Panel
        predivsetEditFieldLabel        matlab.ui.control.Label
        predivsetEditField             matlab.ui.control.NumericEditField
        polepairsetEditFieldLabel      matlab.ui.control.Label
        polepairsetEditField           matlab.ui.control.NumericEditField
        autorestartSwitchLabel         matlab.ui.control.Label
        autorestartSwitch              matlab.ui.control.Switch
        CWCCWSwitchLabel               matlab.ui.control.Label
        CWCCWSwitch                    matlab.ui.control.Switch
        PWMdutyKnobLabel               matlab.ui.control.Label
        PWMdutyKnob                    matlab.ui.control.Knob
        MCenSwitchLabel                matlab.ui.control.Label
        MCenSwitch                     matlab.ui.control.RockerSwitch
        startmotordrivingButton        matlab.ui.control.StateButton
        pbOpenSerial                   matlab.ui.control.StateButton
        Lamp                           matlab.ui.control.Lamp
        DropDownLabel                  matlab.ui.control.Label
        ppCOM                          matlab.ui.control.DropDown
        GAButton                       matlab.ui.control.StateButton
        UIAxes                         matlab.ui.control.UIAxes
        UIAxes2                        matlab.ui.control.UIAxes
        TemperatureEditFieldLabel      matlab.ui.control.Label
        TemperatureEditField           matlab.ui.control.NumericEditField
        CloseLoopLampLabel             matlab.ui.control.Label
        CloseLoopLamp                  matlab.ui.control.Lamp
        StateLampLabel                 matlab.ui.control.Label
        StateLamp                      matlab.ui.control.Lamp
        StuckLampLabel                 matlab.ui.control.Label
        StuckLamp                      matlab.ui.control.Lamp
        PWMdutyGauge                   matlab.ui.control.Gauge
        polepairEditField_2Label       matlab.ui.control.Label
        polepairEditField_2            matlab.ui.control.NumericEditField
        PWMFrequencykHZEditFieldLabel  matlab.ui.control.Label
        PWMFrequencykHZEditField       matlab.ui.control.NumericEditField
        LCDLampLabel                   matlab.ui.control.Label
        LCDLamp                        matlab.ui.control.Lamp
        speedrsEditFieldLabel          matlab.ui.control.Label
        speedrsEditField               matlab.ui.control.EditField
        PWMdutyEditFieldLabel          matlab.ui.control.Label
        PWMdutyEditField               matlab.ui.control.EditField
        tEditFieldLabel                matlab.ui.control.Label
        tEditField                     matlab.ui.control.NumericEditField
        voltageVEditFieldLabel         matlab.ui.control.Label
        voltageVEditField              matlab.ui.control.NumericEditField
        currentAEditFieldLabel         matlab.ui.control.Label
        currentAEditField              matlab.ui.control.NumericEditField
        speedGauge                     matlab.ui.control.Gauge
        runEditFieldLabel              matlab.ui.control.Label
        runEditField                   matlab.ui.control.NumericEditField
        cntEditFieldLabel              matlab.ui.control.Label
        cntEditField                   matlab.ui.control.NumericEditField
        vol_pastEditFieldLabel         matlab.ui.control.Label
        vol_pastEditField              matlab.ui.control.NumericEditField
        cur_pastEditFieldLabel         matlab.ui.control.Label
        cur_pastEditField              matlab.ui.control.NumericEditField
        HighLabel                      matlab.ui.control.Label
        Label_2                        matlab.ui.control.Label
        MadeByBrxLydYxcLabel           matlab.ui.control.Label
    end

    properties (Access = public)
        COM;    % 端口号
        s  ;    %端口设置句柄
        RX_once;%一次接收的数据
        RX_Date;%接收的所以数据
        is_open;%是否打开串口标志位
        
        vol_globle; % 电压中间量
        cur_globle  % Description
        t_globle    % Description
    end


    methods (Access = public)
        function  EveBytesAvailableFcn(app, src, event)
            n = src.BytesAvailable;%获取接收到的字符个数
            if n>0%n>0才继续执行，因为0为0也会触发中断
                app.RX_once=fread(src,n,"char");%读取函数，读取后矩阵为一列
                data=app.RX_once';
                %app.RX_Date =strcat(app.RX_Date, app.RX_once');%字符串拼接，需要转置化
                %app.speedrsEditField.Value = app.RX_Date;
                %app.EditField2.Value = length(app.RX_once);
                    if data(1)=='f'
                       f0=find(data=='f');
                       f1=find(data=='u');
                       f2=find(data=='c');
                       f3=find(data=='k');
                       f4=find(data=='s');
                       f5=find(data=='h');
                       f6=find(data=='i');
                       f7=find(data=='t');
                       f8=find(data=='!');
                       l0=length(f0);
                       l1=length(f1);
                       l2=length(f2);
                       l3=length(f3);
                       l4=length(f4);
                       l5=length(f5);
                       l6=length(f6);
                       l7=length(f7);
                       l8=length(f8);
                       if (l0==1)&&(l1==1)&&(l2==1)&&(l3==1)&&(l4==1)&&(l5==1)&&(l6==1)&&(l7==1)&&(l8==1)&&(f0<f1)&&(f1<f2)&&(f2<f3)&&(f3<f4)&&(f4<f5)&&(f5<f6)&&(f6<f7)&&(f7<f8)
                       f=[f1 f2 f3 f4 f5 f6 f7 f8];
                       %data generate
                       speed=str2num(char(data(1,[2:(f(1)-1)])));
                       voltage=(str2num(char(data(1,[(f(1)+1):(f(2)-1)]))))/10;
                       current=(str2num(char(data(1,[(f(2)+1):(f(3)-1)]))))/10;
                       pwm_level=str2num(char(data(1,[(f(3)+1):(f(4)-1)])));
                       pole_pair=str2num(char(data(1,[(f(4)+1):(f(5)-1)])));
                       pwm_frequence=str2num(char(data(1,[(f(5)+1):(f(6)-1)])));
                       temperature=str2num(char(data(1,[(f(6)+1):(f(7)-1)])));
                       state_lamp=str2num(char(data(1,[(f(7)+1):(f(8)-1)]))');
                       if((speed>=0)&&(speed<15000)&&(voltage>=0)&&(voltage<20)&&(current>=0)&&(current<99)&&(pwm_level>=10)&&(pwm_level<256)&&(pole_pair>=0)&&(pole_pair<=99)&&(pwm_frequence>=256)&&(pwm_frequence<3841)&&(temperature>=0)&&(temperature<1000)&&(length(state_lamp)==5))
                       %gui generate
                       app.speedGauge.Value = speed;%speed gauge
                       app.speedrsEditField.Value =char(data(1,[2:(f(1)-1)]));%speed value
                       app.PWMdutyGauge.Value = pwm_level;%pwm_leve
                       app.PWMdutyEditField.Value = char(data(1,[(f(3)+1):(f(4)-1)]));%pwm_level value
                       app.polepairEditField_2.Value = pole_pair;%pole_pair
                       app.TemperatureEditField.Value = temperature/10;%temperature
                       app.PWMFrequencykHZEditField.Value = 24000/pwm_frequence;%pwm_frequence
                       %switch rebound
                       if (app.runEditField.Value==1)&&((state_lamp(5)==1)||((state_lamp(3)==1)&&(strcmp(app.autorestartSwitch.Value,'Off'))))
                               app.MCenSwitch.Value='Off';
                       end
                       %state lamp
                       if state_lamp(5)==1
                           app.StateLamp.Color = 'r';
                       elseif state_lamp(1)==2
                           app.StateLamp.Color = 'g';
                       else
                           app.StateLamp.Color = [0.8 0.8 0.8];
                       end
                       %stuck lamp
                       if state_lamp(3)==1
                           app.StuckLamp.Color = 'r';
                       else
                           app.StuckLamp.Color = [0.8 0.8 0.8];
                       end
                       %close loop lamp
                       if state_lamp(2)==1
                           app.CloseLoopLamp.Color = 'g';
                       else
                           app.CloseLoopLamp.Color = [0.8 0.8 0.8];
                       end
                       %lcd lamp
                       if state_lamp(4)==1
                           app.LCDLamp.Color = 'g';
                       else
                           app.LCDLamp.Color = [0.8 0.8 0.8];
                       end
                       %voltage diagram & current diagram
                       app.vol_globle(app.cntEditField.Value + 1)=voltage;
                       app.cur_globle(app.cntEditField.Value + 1)=current;
                       app.t_globle(app.cntEditField.Value +1)=app.tEditField.Value;
                       if app.cntEditField.Value == 19
                       hold(app.UIAxes,'on')
                       hold(app.UIAxes2,'on')
%                        t=[app.tEditField.Value-1 (app.tEditField.Value)];
%                        vol_past = app.voltageVEditField.Value;
%                        cur_past = app.currentAEditField.Value;
%                        vol=[vol_past voltage];
%                        cur=[cur_past current];
                       plot(app.UIAxes,[(app.t_globle(1)-0.05) app.t_globle],[app.vol_pastEditField.Value app.vol_globle],'LineWidth',2)
                       plot(app.UIAxes2,[(app.t_globle(1)-0.05) app.t_globle],[app.cur_pastEditField.Value app.cur_globle],'LineWidth',2) 
                       axis(app.UIAxes,[app.tEditField.Value-5 app.tEditField.Value+1 voltage-1 voltage+1])
                       axis(app.UIAxes2,[app.tEditField.Value-5 app.tEditField.Value+1 current-0.5 current+0.5])
                       app.vol_pastEditField.Value = voltage;
                       app.cur_pastEditField.Value = current;
                       end
                       app.voltageVEditField.Value=voltage;
                       app.currentAEditField.Value=current;
                       if app.cntEditField.Value == 19
                           app.cntEditField.Value=0;
                       else
                           app.cntEditField.Value=app.cntEditField.Value+1;
                       end
                       app.tEditField.Value = app.tEditField.Value + 0.05;
                       %control mode select
%                        if app.startmotordrivingButton.Value == 0
%                            app.PWMdutyKnob.Value = pwm_level;
%                            if state_lamp(1)==1
%                                app.MCenSwitch.Value = 'On';
%                            else
%                                app.MCenSwitch.Value = 'Off';
%                            end
%                        else
%                            if state_lamp(5)==1
%                                app.startmotordrivingButton.Value = 0;
%                                app.PWMdutyKnob.Enable = 0;
%                                app.MCenSwitch.Enable = 0;
%                                app.predivsetEditField.Enable = 1;
%                                app.polepairsetEditField.Enable = 1;
%                                app.autorestartSwitch.Enable = 1;
%                                app.CWCCWSwitch.Enable = 1;
%                            elseif state_lamp(1)==1
%                                app.startmotordrivingButton.Value = 0;
%                                app.PWMdutyKnob.Enable = 0;
%                                app.MCenSwitch.Enable = 0;
%                                app.predivsetEditField.Enable = 1;
%                                app.polepairsetEditField.Enable = 1;
%                                app.autorestartSwitch.Enable = 1;
%                                app.CWCCWSwitch.Enable = 1;
%                            end
%                        end
                        %check run
                        if state_lamp(5)==1
                           app.runEditField.Value=0;
                        elseif state_lamp(1)==2
                           app.runEditField.Value=1;
                        else
                           app.runEditField.Value=0;
                        end
                        %color set & warning
                        if temperature < 600
                            app.TemperatureEditField.FontColor = [0 0 0];
                            app.HighLabel.Visible = 0;
                        elseif (temperature>=600)&&(temperature<700)
                            app.TemperatureEditField.FontColor = [0.93 0.69 0.13];
                            app.HighLabel.Visible = 0;
                        else
                            app.TemperatureEditField.FontColor = 'r';
                            app.HighLabel.Visible = 1;
                        end
                        
                        if voltage<9
                            app.voltageVEditField.FontColor = [0.93 0.69 0.13];
                            app.Label_2.Visible = 1;
                            app.Label_2.Text = 'Low!';
                            app.Label_2.FontColor = [0.93 0.69 0.13];
                        elseif (voltage>=9)&&voltage<18
                            app.voltageVEditField.FontColor = [0 0 0];
                            app.Label_2.Visible = 0;
                        else
                            app.voltageVEditField.FontColor = 'r';
                            app.Label_2.Visible = 1;
                            app.Label_2.Text = 'High!';
                            app.Label_2.FontColor = 'r';
                        end
                       end
                       end
                    end
            end
        end
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            app.is_open=0;
            scoms = instrfind;%获取占用的串口号
            if scoms ~= 0%如果存在则关闭，否则不能打开
                stopasync(scoms);
                fclose(scoms);
                delete(scoms);
            end
        end

        % Value changed function: startmotordrivingButton
        function startmotordrivingButtonValueChanged(app, event)
            value = app.startmotordrivingButton.Value;
            %lock the setting
            if value ==1
                app.predivsetEditField.Enable = 0;
                app.GAButton.Enable = 0;
                app.polepairsetEditField.Enable = 0;
                app.autorestartSwitch.Enable = 0;
                app.CWCCWSwitch.Enable = 0;
                app.PWMdutyKnob.Enable = 1;
                app.MCenSwitch.Enable = 1;
                app.startmotordrivingButton.Text = 'Restart';
            else
                app.PWMdutyKnob.Enable = 0;
                app.MCenSwitch.Enable = 0;
                app.startmotordrivingButton.Text = 'start motor driving';
                app.PWMdutyKnob.Value = 15;
                app.MCenSwitch.Value = 'Off';
                fclose(app.s);
                app.pbOpenSerial.Text='打开串口';
                app.Lamp.Color=[0.15 0.15 0.15];
                %safety
                app.predivsetEditField.Enable = 0;
                app.GAButton.Enable = 0;
                app.polepairsetEditField.Enable = 0;
                app.autorestartSwitch.Enable = 0;
                app.CWCCWSwitch.Enable = 0;
                app.startmotordrivingButton.Enable = 0;
                app.pbOpenSerial.Value = 0;
                app.GAButton.Value = 0;
            end
            %data send
            if value == 1
                if app.GAButton.Value == 1
                    m1 = '0';
                else
                    m1 = num2str(app.predivsetEditField.Value);
                end      
                m2 = num2str(app.polepairsetEditField.Value);
                if strcmp(app.autorestartSwitch.Value,'On')
                    m3 = '1';
                else
                    m3 = '0';
                end
                if strcmp(app.CWCCWSwitch.Value,'CW')
                    m4 = '1';
                else
                    m4 = '0';
                end
                tx_data = strcat('d',m1,'a',m2,'m',m3,m4,'n');
                for i=1:10
                fwrite(app.s,tx_data);
                pause(0.05)
                end
%             else
%                 fwrite(app.s,'restart');
            end
        end

        % Value changing function: PWMdutyKnob
        function PWMdutyKnobValueChanging(app, event)
            changingValue = event.Value;
            m = num2str(round(changingValue));
            tx_data = strcat('p',m,'h');
            fwrite(app.s,tx_data);
        end

        % Value changed function: pbOpenSerial
        function pbOpenSerialValueChanged(app, event)
            app.COM=get(app.ppCOM,'Value');
            if strcmp(get(app.pbOpenSerial,'Text'),'打开串口')
                try
                    app.s=serial(app.COM);
                    app.s.BaudRate=38400;%设置波特率
                    app.s.DataBits=8;%设置数据长度
                    app.s.StopBits=1;%设置停止位长度
                    app.s.InputBufferSize=1024;%设置输入缓冲区大小为1M
                    app.s.BytesAvailableFcnMode='terminator';  %串口事件回调设置
                    app.s.Terminator='!';
%                     app.s.BytesAvailableFcnCount=10; %输入缓冲区存在10个字节触发回调函数
                    app.s.BytesAvailableFcn={@app.EveBytesAvailableFcn};%回调函数的指定
                    fopen(app.s);%打开串口
                    app.is_open=1;
                    app.pbOpenSerial.Text='关闭串口';
                    app.Lamp.Color=[0 1 0];
                    %safety
                    app.predivsetEditField.Enable = 1;
                    app.GAButton.Enable =1;
                    app.polepairsetEditField.Enable = 1;
                    app.autorestartSwitch.Enable = 1;
                    app.CWCCWSwitch.Enable = 1;
                    app.startmotordrivingButton.Enable = 1;
                    %
                catch err
                    msgbox('打开失败');
                end
            else
                try
                    fclose(app.s);
                    app.pbOpenSerial.Text='打开串口';
                    app.Lamp.Color=[0.15 0.15 0.15];
                    %safety
                    app.predivsetEditField.Enable = 0;
                    app.GAButton.Enable = 0;
                    app.polepairsetEditField.Enable = 0;
                    app.autorestartSwitch.Enable = 0;
                    app.CWCCWSwitch.Enable = 0;
                    app.startmotordrivingButton.Enable = 0;
                    %
                catch err
                    msgbox('关闭失败');
                end
                delete(app.s);
                app.is_open=0;
            end
        end

        % Close request function: UIFigure
        function UIFigureCloseRequest(app, event)
            delete(app)
            
        end

        % Value changed function: voltageVEditField
        function voltageVEditFieldValueChanged(app, event)
            value = app.voltageVEditField.Value;
            
        end

        % Value changed function: MCenSwitch
        function MCenSwitchValueChanged(app, event)
            value = app.MCenSwitch.Value;
            if strcmp(value,'On')
                m = '1';
            else
                m = '0';
            end
            tx_data = strcat('f',m,'s');
            fwrite(app.s,tx_data);
        end

        % Value changed function: GAButton
        function GAButtonValueChanged(app, event)
            value = app.GAButton.Value;
            if value ==1
                app.predivsetEditField.Enable = 0;
            else
                app.predivsetEditField.Enable = 1;
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'UI Figure';
            app.UIFigure.CloseRequestFcn = createCallbackFcn(app, @UIFigureCloseRequest, true);
            app.Image = uiimage(app.UIFigure);
            app.Image.ImageSource = 'FUCK.png';
            app.Panel = uipanel(app.UIFigure);
            app.predivsetEditFieldLabel = uilabel(app.Panel);
            app.predivsetEditFieldLabel.Text = 'prediv set';
            app.predivsetEditField = uieditfield(app.Panel, 'numeric');
            app.predivsetEditField.Enable = 'off';
            app.polepairsetEditFieldLabel = uilabel(app.Panel);
            app.polepairsetEditFieldLabel.Text = 'pole pair set';
            app.polepairsetEditField = uieditfield(app.Panel, 'numeric');
            app.polepairsetEditField.Enable = 'off';
            app.autorestartSwitchLabel = uilabel(app.Panel);
            app.autorestartSwitchLabel.Text = 'auto restart';
            app.autorestartSwitch = uiswitch(app.Panel, 'slider');
            app.autorestartSwitch.Enable = 'off';
            app.CWCCWSwitchLabel = uilabel(app.Panel);
            app.CWCCWSwitchLabel.Text = 'CW / CCW';
            app.CWCCWSwitch = uiswitch(app.Panel, 'slider');
            app.CWCCWSwitch.Items = {'CW', 'CCW'};
            app.CWCCWSwitch.Enable = 'off';
            app.CWCCWSwitch.Value = 'CW';
            app.PWMdutyKnobLabel = uilabel(app.Panel);
            app.PWMdutyKnobLabel.Text = 'PWM duty';
            app.PWMdutyKnob = uiknob(app.Panel, 'continuous');
            app.PWMdutyKnob.Limits = [15 255];
            app.PWMdutyKnob.MajorTicks = [15 45 75 105 135 165 195 225 255];
            app.PWMdutyKnob.MajorTickLabels = {'15', '45', '75', '105', '135', '165', '195', '225', '255'};
            app.PWMdutyKnob.ValueChangingFcn = createCallbackFcn(app, @PWMdutyKnobValueChanging, true);
            app.PWMdutyKnob.MinorTicks = [30 60 90 120 150 180 210 240];
            app.PWMdutyKnob.Enable = 'off';
            app.PWMdutyKnob.Value = 15;
            app.MCenSwitchLabel = uilabel(app.Panel);
            app.MCenSwitchLabel.Text = 'MCen';
            app.MCenSwitch = uiswitch(app.Panel, 'rocker');
            app.MCenSwitch.ValueChangedFcn = createCallbackFcn(app, @MCenSwitchValueChanged, true);
            app.MCenSwitch.Enable = 'off';
            app.startmotordrivingButton = uibutton(app.Panel, 'state');
            app.startmotordrivingButton.ValueChangedFcn = createCallbackFcn(app, @startmotordrivingButtonValueChanged, true);
            app.startmotordrivingButton.Enable = 'off';
            app.startmotordrivingButton.Text = 'start motor driving';
            app.pbOpenSerial = uibutton(app.Panel, 'state');
            app.pbOpenSerial.ValueChangedFcn = createCallbackFcn(app, @pbOpenSerialValueChanged, true);
            app.pbOpenSerial.Text = '打开串口';
            app.Lamp = uilamp(app.Panel);
            app.DropDownLabel = uilabel(app.Panel);
            app.DropDownLabel.Text = '串口';
            app.ppCOM = uidropdown(app.Panel);
            app.ppCOM.Items = {'COM1', 'COM2', 'COM3', 'COM4', 'COM5', 'COM9', 'COM10', 'COM12'};
            app.ppCOM.Value = 'COM1';
            app.GAButton = uibutton(app.Panel, 'state');
            app.GAButton.ValueChangedFcn = createCallbackFcn(app, @GAButtonValueChanged, true);
            app.GAButton.Enable = 'off';
            app.GAButton.Tooltip = {'use Genetic Algorithm to set prediv'};
            app.GAButton.Text = 'GA';
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, 'Voltage Diagram')
            xlabel(app.UIAxes, 't')
            ylabel(app.UIAxes, 'voltage(V)')
            app.UIAxes2 = uiaxes(app.UIFigure);
            title(app.UIAxes2, 'Current Diagram')
            xlabel(app.UIAxes2, 't')
            ylabel(app.UIAxes2, 'current(A)')
            app.TemperatureEditFieldLabel = uilabel(app.UIFigure);
            app.TemperatureEditFieldLabel.Text = 'Temperature(℃)';
            app.TemperatureEditField = uieditfield(app.UIFigure, 'numeric');
            app.TemperatureEditField.Editable = 'off';
            app.CloseLoopLampLabel = uilabel(app.UIFigure);
            app.CloseLoopLampLabel.Text = 'Close Loop';
            app.CloseLoopLamp = uilamp(app.UIFigure);
            app.CloseLoopLamp.Tooltip = {'whether close loop'};
            app.StateLampLabel = uilabel(app.UIFigure);
            app.StateLampLabel.Text = 'State Lamp';
            app.StateLamp = uilamp(app.UIFigure);
            app.StateLamp.Tooltip = {'red for warning'; 'green for run'; 'grey for stop'};
            app.StuckLampLabel = uilabel(app.UIFigure);
            app.StuckLampLabel.Text = 'Stuck';
            app.StuckLamp = uilamp(app.UIFigure);
            app.StuckLamp.Tooltip = {'red for stuck'};
            app.PWMdutyGauge = uigauge(app.UIFigure, 'circular');
            app.PWMdutyGauge.Limits = [0 255];
            app.polepairEditField_2Label = uilabel(app.UIFigure);
            app.polepairEditField_2Label.Text = 'pole pair';
            app.polepairEditField_2 = uieditfield(app.UIFigure, 'numeric');
            app.polepairEditField_2.Editable = 'off';
            app.PWMFrequencykHZEditFieldLabel = uilabel(app.UIFigure);
            app.PWMFrequencykHZEditFieldLabel.Text = 'PWM Frequency(kHZ)';
            app.PWMFrequencykHZEditField = uieditfield(app.UIFigure, 'numeric');
            app.PWMFrequencykHZEditField.Editable = 'off';
            app.LCDLampLabel = uilabel(app.UIFigure);
            app.LCDLampLabel.Text = 'LCD';
            app.LCDLamp = uilamp(app.UIFigure);
            app.LCDLamp.Tooltip = {'whether connect LCD'};
            app.speedrsEditFieldLabel = uilabel(app.UIFigure);
            app.speedrsEditFieldLabel.Text = 'speed(r/s)';
            app.speedrsEditField = uieditfield(app.UIFigure, 'text');
            app.speedrsEditField.Editable = 'off';
            app.speedrsEditField.Value = '0';
            app.PWMdutyEditFieldLabel = uilabel(app.UIFigure);
            app.PWMdutyEditFieldLabel.Text = 'PWM duty';
            app.PWMdutyEditField = uieditfield(app.UIFigure, 'text');
            app.PWMdutyEditField.Editable = 'off';
            app.PWMdutyEditField.Value = '0';
            app.tEditFieldLabel = uilabel(app.UIFigure);
            app.tEditFieldLabel.Enable = 'off';
            app.tEditFieldLabel.Visible = 'off';
            app.tEditFieldLabel.Text = 't';
            app.tEditField = uieditfield(app.UIFigure, 'numeric');
            app.tEditField.Enable = 'off';
            app.tEditField.Visible = 'off';
            app.voltageVEditFieldLabel = uilabel(app.UIFigure);
            app.voltageVEditFieldLabel.Text = 'voltage(V)';
            app.voltageVEditField = uieditfield(app.UIFigure, 'numeric');
            app.voltageVEditField.ValueChangedFcn = createCallbackFcn(app, @voltageVEditFieldValueChanged, true);
            app.voltageVEditField.Editable = 'off';
            app.currentAEditFieldLabel = uilabel(app.UIFigure);
            app.currentAEditFieldLabel.Text = 'current(A)';
            app.currentAEditField = uieditfield(app.UIFigure, 'numeric');
            app.currentAEditField.Editable = 'off';
            app.speedGauge = uigauge(app.UIFigure, 'circular');
            app.speedGauge.Limits = [0 15000];
            app.speedGauge.MajorTicks = [0 2500 5000 7500 10000 12500 15000];
            app.speedGauge.MajorTickLabels = {'0', '2.5k', '5k', '7.5k', '10k', '12.5k', '15k'};
            app.speedGauge.MinorTicks = [0 125 250 375 500 625 750 875 1000 1125 1250 1375 1500 1625 1750 1875 2000 2125 2250 2375 2500 2625 2750 2875 3000 3125 3250 3375 3500 3625 3750 3875 4000 4125 4250 4375 4500 4625 4750 4875 5000 5125 5250 5375 5500 5625 5750 5875 6000 6125 6250 6375 6500 6625 6750 6875 7000 7125 7250 7375 7500 7625 7750 7875 8000 8125 8250 8375 8500 8625 8750 8875 9000 9125 9250 9375 9500 9625 9750 9875 10000 10125 10250 10375 10500 10625 10750 10875 11000 11125 11250 11375 11500 11625 11750 11875 12000 12125 12250 12375 12500 12625 12750 12875 13000 13125 13250 13375 13500 13625 13750 13875 14000 14125 14250 14375 14500 14625 14750 14875 15000];
            app.runEditFieldLabel = uilabel(app.UIFigure);
            app.runEditFieldLabel.Enable = 'off';
            app.runEditFieldLabel.Visible = 'off';
            app.runEditFieldLabel.Text = 'run';
            app.runEditField = uieditfield(app.UIFigure, 'numeric');
            app.runEditField.Editable = 'off';
            app.runEditField.Enable = 'off';
            app.runEditField.Visible = 'off';
            app.cntEditFieldLabel = uilabel(app.UIFigure);
            app.cntEditFieldLabel.Enable = 'off';
            app.cntEditFieldLabel.Visible = 'off';
            app.cntEditFieldLabel.Text = 'cnt';
            app.cntEditField = uieditfield(app.UIFigure, 'numeric');
            app.cntEditField.Editable = 'off';
            app.cntEditField.Enable = 'off';
            app.cntEditField.Visible = 'off';
            app.vol_pastEditFieldLabel = uilabel(app.UIFigure);
            app.vol_pastEditFieldLabel.Enable = 'off';
            app.vol_pastEditFieldLabel.Visible = 'off';
            app.vol_pastEditFieldLabel.Text = 'vol_past';
            app.vol_pastEditField = uieditfield(app.UIFigure, 'numeric');
            app.vol_pastEditField.Enable = 'off';
            app.vol_pastEditField.Visible = 'off';
            app.cur_pastEditFieldLabel = uilabel(app.UIFigure);
            app.cur_pastEditFieldLabel.Enable = 'off';
            app.cur_pastEditFieldLabel.Visible = 'off';
            app.cur_pastEditFieldLabel.Text = 'cur_past';
            app.cur_pastEditField = uieditfield(app.UIFigure, 'numeric');
            app.cur_pastEditField.Enable = 'off';
            app.cur_pastEditField.Visible = 'off';
            app.HighLabel = uilabel(app.UIFigure);
            app.HighLabel.Visible = 'off';
            app.HighLabel.Text = 'High！';
            app.Label_2 = uilabel(app.UIFigure);
            app.Label_2.Visible = 'off';
            app.MadeByBrxLydYxcLabel = uilabel(app.UIFigure);
            app.MadeByBrxLydYxcLabel.Text = 'Made By Brx,Lyd,Yxc';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = BLDC

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end